classdef kadai1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        edgeButton          matlab.ui.control.Button
        binarizationButton  matlab.ui.control.Button
        loadButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            filename = uigetfile('/MATLAB Drive/logo_csse..png');
            fig = app.UIFigure;
            ax = uiaxes(fig,'Position',[60,320,272,157]);
            % read image
            im=imread(filename);
            % % plot image
            imshow(im,'Parent',ax);
        end

        % Button pushed function: binarizationButton
        function binarizationButtonPushed(app, event)
            % get filename
            filename = uigetfile ('/MATLAB Drive/logo_csse..png');
            fig = app.UIFigure;
            ax = uiaxes(fig,'Position',[60,180,272,157]);
            % read image
            im = imread(filename);
            % compute image
            imgray = rgb2gray(im);
            BW = imbinarize(imgray);
            %plot images
            imshow(BW*255,'Parent',ax)
        end

        % Button pushed function: edgeButton
        function edgeButtonPushed(app, event)
            % get filename
            filename = uigetfile ('/MATLAB Drive/logo_csse..png');
            fig = app.UIFigure;
            ax = uiaxes(fig,'Position',[60,40,272,157]);
            % read image
            im = imread(filename);
            % compute image
            imgray = rgb2gray(im);
            edges = edge(imgray, 'Canny');

            %plot images
            imshow(edges,'Parent',ax)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.binarizationButton = uibutton(app.UIFigure, 'push');
            app.binarizationButton.ButtonPushedFcn = createCallbackFcn(app, @binarizationButtonPushed, true);
            app.binarizationButton.Text = 'binarization';
            app.edgeButton = uibutton(app.UIFigure, 'push');
            app.edgeButton.ButtonPushedFcn = createCallbackFcn(app, @edgeButtonPushed, true);
            app.edgeButton.Text = 'edge';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kadai1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end